classdef ffauxivsolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        UndoButton         matlab.ui.control.Button
        GiftButton         matlab.ui.control.StateButton
        ResetButton        matlab.ui.control.Button
        NameTextArea       matlab.ui.control.TextArea
        NameTextAreaLabel  matlab.ui.control.Label
        TellButton         matlab.ui.control.StateButton
        Erase_Button       matlab.ui.control.StateButton
        Fox_Button         matlab.ui.control.StateButton
        Box_Button         matlab.ui.control.StateButton
        Miss_Button        matlab.ui.control.StateButton
        Swords_Button      matlab.ui.control.StateButton
        Block_Button       matlab.ui.control.StateButton
        Button_36          matlab.ui.control.Button
        Button_35          matlab.ui.control.Button
        Button_34          matlab.ui.control.Button
        Button_33          matlab.ui.control.Button
        Button_32          matlab.ui.control.Button
        Button_31          matlab.ui.control.Button
        Button_30          matlab.ui.control.Button
        Button_29          matlab.ui.control.Button
        Button_28          matlab.ui.control.Button
        Button_27          matlab.ui.control.Button
        Button_26          matlab.ui.control.Button
        Button_25          matlab.ui.control.Button
        Button_24          matlab.ui.control.Button
        Button_23          matlab.ui.control.Button
        Button_22          matlab.ui.control.Button
        Button_21          matlab.ui.control.Button
        Button_20          matlab.ui.control.Button
        Button_19          matlab.ui.control.Button
        Button_18          matlab.ui.control.Button
        Button_17          matlab.ui.control.Button
        Button_16          matlab.ui.control.Button
        Button_15          matlab.ui.control.Button
        Button_14          matlab.ui.control.Button
        Button_13          matlab.ui.control.Button
        Button_12          matlab.ui.control.Button
        Button_11          matlab.ui.control.Button
        Button_10          matlab.ui.control.Button
        Button_9           matlab.ui.control.Button
        Button_8           matlab.ui.control.Button
        Button_7           matlab.ui.control.Button
        Button_6           matlab.ui.control.Button
        Button_5           matlab.ui.control.Button
        Button_4           matlab.ui.control.Button
        Button_3           matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
    end

    
    properties (Access = private)
        brush = 0
        pattern = nan(6)
        lastPattern = nan(6)
    end
    
    methods (Access = private)
        
        function buttons = patternButtons(app)
            buttons = [app.Button, ...
                app.Button_7, ...
                app.Button_13, ...
                app.Button_19, ...
                app.Button_25, ...
                app.Button_31, ...
                app.Button_2, ...
                app.Button_8, ...
                app.Button_14, ...
                app.Button_20, ...
                app.Button_26, ...
                app.Button_32, ...
                app.Button_3, ...
                app.Button_9, ...
                app.Button_15, ...
                app.Button_21, ...
                app.Button_27, ...
                app.Button_33, ...
                app.Button_4, ...
                app.Button_10, ...
                app.Button_16, ...
                app.Button_22, ...
                app.Button_28, ...
                app.Button_34, ...
                app.Button_5, ...
                app.Button_11, ...
                app.Button_17, ...
                app.Button_23, ...
                app.Button_29, ...
                app.Button_35, ...
                app.Button_6, ...
                app.Button_12, ...
                app.Button_18, ...
                app.Button_24, ...
                app.Button_30, ...
                app.Button_36];
        end
        
        function resetBrushButtons(app, button)
            arguments
                app (1,1)
                button = []
            end
            if isempty(button) || button~=app.Block_Button
                app.Block_Button.Value = false;
            end
            if isempty(button) || button~=app.Swords_Button
                app.Swords_Button.Value = false;
            end
            if isempty(button) || button~=app.Box_Button
                app.Box_Button.Value = false;
            end
            if isempty(button) || button~=app.Fox_Button
                app.Fox_Button.Value = false;
            end
            if isempty(button) || button~=app.Miss_Button
                app.Miss_Button.Value = false;
            end
            if isempty(button) || button~=app.Erase_Button
                app.Erase_Button.Value = false;
            end
        end
        
        function recalculate(app)
            load patterns.mat patterns names chances

            possiblePatternFilter = all(app.pattern == patterns | isnan(app.pattern), [1 2]);
            possiblePatterns = patterns(:, :, possiblePatternFilter);
            possibleNames = names(:, :, possiblePatternFilter);
            possibleChances = chances(:, :, possiblePatternFilter);
            possibleChances = possibleChances/sum(possibleChances);

            if isempty(possibleNames)
                resetBrushButtons(app);
                app.Erase_Button.Value = true;
                app.brush = NaN;
                app.NameTextArea.Value = "Double check pattern";
            else
                forcedPatternLocations = find(all(possiblePatterns(:, :, 1) == possiblePatterns, 3));
                app.pattern(forcedPatternLocations) = possiblePatterns(forcedPatternLocations);
    
                forcedName = extractBefore(possibleNames{1}, 1+sum(all(possibleNames{1}' == reshape([possibleNames{:}], 5, []), 2)));
                app.NameTextArea.Value = forcedName;

                p0 = sum((possiblePatterns == 0).*possibleChances, 3);
                p1 = sum((possiblePatterns == 1).*possibleChances, 3);
                p2 = sum((possiblePatterns == 2).*possibleChances, 3);
                p3 = sum((possiblePatterns == 3).*possibleChances, 3);
                p4 = sum((possiblePatterns == 4).*possibleChances, 3);
                shannon = -sum(cat(3, p0.*log2(p0).*(p0>0), p1.*log2(p1).*(p1>0), p2.*log2(p2).*(p2>0), p3.*log2(p3).*(p3>0), p4.*log2(p4).*(p4>0)), 3, "omitnan");
            end

            if all(isnan(app.pattern), [1 2]) && isnan(app.brush)
                resetBrushButtons(app)
                app.Block_Button.Value = true;
                app.brush = 0;
            end

            if sum(app.pattern == 0, [1 2]) == 5 && app.brush == 0
                resetBrushButtons(app)
                app.Swords_Button.Value = true;
                app.brush = 3;
            end

            if sum(app.pattern == 3, [1 2]) == 6 && app.brush == 3
                resetBrushButtons(app)
                app.Box_Button.Value = true;
                app.brush = 2;
            end

            if sum(app.pattern == 2, [1 2]) == 4 && app.brush == 2
                resetBrushButtons(app)
                app.Fox_Button.Value = true;
                app.brush = 4;
            end

            if app.brush == 0 || isnan(app.brush)
                recommendedSquare = [];
            else
                recommendedSquare = find(shannon==max(shannon.*any(possiblePatterns==app.brush,3), [], 'all'));
            end
            
            potentialSquares = find(any(isnan(app.pattern) & possiblePatterns == app.brush, 3));

            buttons = app.patternButtons();
            for iB = 1:36
                if isnan(app.pattern(iB))
                    if any(iB == potentialSquares)
                        switch app.brush
                            case 0
                                buttons(iB).BackgroundColor = [109 96 89]/255;
                            case 1
                                buttons(iB).BackgroundColor = [67 56 49]/255;
                            case 2
                                buttons(iB).BackgroundColor = [72 91 71]/255;
                            case 3
                                buttons(iB).BackgroundColor = [63 89 104]/255;
                            case 4
                                buttons(iB).BackgroundColor = [127 90 67]/255;
                        end
                    else
                        buttons(iB).BackgroundColor = [67 56 49]/255;
                    end
                    if any(iB == recommendedSquare)
                        buttons(iB).BackgroundColor = [0 0 0];
                        buttons(iB).Text = "❔";
                    else
                        buttons(iB).Text = "❓";
                    end
                else
                    switch app.pattern(iB)
                        case 0
                            buttons(iB).BackgroundColor = [154 140 132]/255;
                            buttons(iB).Text = "🚫";
                        case 1
                            buttons(iB).BackgroundColor = [250 235 226]/255;
                            buttons(iB).Text = "😐";
                        case 2
                            buttons(iB).BackgroundColor = [74 167 71]/255;
                            if app.GiftButton.Value
                                buttons(iB).Text = "📦";
                            else
                                buttons(iB).Text = "🎁";
                            end
                        case 3
                            buttons(iB).BackgroundColor = [25 172 231]/255;
                            buttons(iB).Text = "⚔️";
                        case 4
                            buttons(iB).BackgroundColor = [233 152 99]/255;
                            buttons(iB).Text = "🦊";
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Block_Button.Value = true;
            recalculate(app);
        end

        % Value changed function: Block_Button
        function Block_ButtonValueChanged(app, event)
            resetBrushButtons(app, event.Source)
            app.brush = 0;
            recalculate(app);
        end

        % Value changed function: Swords_Button
        function Swords_ButtonValueChanged(app, event)
            resetBrushButtons(app, event.Source)
            app.brush = 3;
            recalculate(app);
        end

        % Value changed function: Miss_Button
        function Miss_ButtonValueChanged(app, event)
            resetBrushButtons(app, event.Source)
            app.brush = 1;
            recalculate(app);
        end

        % Value changed function: Box_Button
        function Box_ButtonValueChanged(app, event)
            resetBrushButtons(app, event.Source)
            app.brush = 2;
            recalculate(app);
        end

        % Value changed function: Fox_Button
        function Fox_ButtonValueChanged(app, event)
            resetBrushButtons(app, event.Source)
            app.brush = 4;
            recalculate(app);
        end

        % Value changed function: Erase_Button
        function Erase_ButtonValueChanged(app, event)
            resetBrushButtons(app, event.Source)
            app.brush = NaN;
            recalculate(app);
        end

        % Button pushed function: Button, Button_10, Button_11, Button_12, 
        % ...and 32 other components
        function Grid_ButtonPushed(app, event)
            app.lastPattern = app.pattern;
            app.pattern(event.Source.Layout.Row-1, event.Source.Layout.Column) = app.brush;
            recalculate(app);
        end

        % Value changed function: TellButton
        function TellButtonValueChanged(app, event)
            value = app.TellButton.Value;
            switch value
                case true
                    app.TellButton.Text = "Retell";
                case false
                    app.TellButton.Text = "Tell";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.lastPattern = app.pattern;
            app.pattern = nan(6);
            resetBrushButtons(app);
            app.Block_Button.Value = true;
            app.brush = 0;
            recalculate(app);
        end

        % Value changed function: GiftButton
        function GiftButtonValueChanged(app, event)
            value = app.GiftButton.Value;
            switch value
                case true
                    app.GiftButton.Text = "📦";
                    app.Box_Button.Text = ["Box", "📦"];
                case false
                    app.GiftButton.Text= "🎁";
                    app.Box_Button.Text = ["Box", "🎁"];
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            temp = app.lastPattern;
            app.lastPattern = app.pattern;
            app.pattern = temp;
            recalculate(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.GridLayout, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.GridLayout, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.GridLayout, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.GridLayout, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.GridLayout, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.GridLayout, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.GridLayout, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.GridLayout, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.GridLayout, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.GridLayout, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.GridLayout, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.GridLayout, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.GridLayout, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.GridLayout, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.GridLayout, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.GridLayout, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.GridLayout, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.GridLayout, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.GridLayout, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.GridLayout, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.GridLayout, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_36.Text = '';
            app.Block_Button = uibutton(app.GridLayout, 'state');
            app.Block_Button.ValueChangedFcn = createCallbackFcn(app, @Block_ButtonValueChanged, true);
            app.Block_Button.Text = {'Block'; '🚫'};
            app.Swords_Button = uibutton(app.GridLayout, 'state');
            app.Swords_Button.ValueChangedFcn = createCallbackFcn(app, @Swords_ButtonValueChanged, true);
            app.Swords_Button.Text = {'Swords'; '⚔️'};
            app.Miss_Button = uibutton(app.GridLayout, 'state');
            app.Miss_Button.ValueChangedFcn = createCallbackFcn(app, @Miss_ButtonValueChanged, true);
            app.Miss_Button.Text = {'Miss'; '😐'};
            app.Box_Button = uibutton(app.GridLayout, 'state');
            app.Box_Button.ValueChangedFcn = createCallbackFcn(app, @Box_ButtonValueChanged, true);
            app.Box_Button.Text = {'Box'; '🎁'};
            app.Fox_Button = uibutton(app.GridLayout, 'state');
            app.Fox_Button.ValueChangedFcn = createCallbackFcn(app, @Fox_ButtonValueChanged, true);
            app.Fox_Button.Text = {'Fox'; '🦊'};
            app.Erase_Button = uibutton(app.GridLayout, 'state');
            app.Erase_Button.ValueChangedFcn = createCallbackFcn(app, @Erase_ButtonValueChanged, true);
            app.Erase_Button.Text = {'Erase'; '❓'};
            app.TellButton = uibutton(app.GridLayout, 'state');
            app.TellButton.ValueChangedFcn = createCallbackFcn(app, @TellButtonValueChanged, true);
            app.TellButton.Text = 'Tell';
            app.NameTextAreaLabel = uilabel(app.GridLayout);
            app.NameTextAreaLabel.Text = 'Name:';
            app.NameTextArea = uitextarea(app.GridLayout);
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GiftButton = uibutton(app.GridLayout, 'state');
            app.GiftButton.ValueChangedFcn = createCallbackFcn(app, @GiftButtonValueChanged, true);
            app.GiftButton.Text = 'Gift';
            app.UndoButton = uibutton(app.GridLayout, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ffauxivsolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end